classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        BreakpointsEditField        matlab.ui.control.NumericEditField
        BreakpointsEditFieldLabel   matlab.ui.control.Label
        GenerateButton              matlab.ui.control.Button
        EndtimeEditField            matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel       matlab.ui.control.Label
        StarttimeEditField          matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel     matlab.ui.control.Label
        SamplingrateEditField       matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        positions % Description
        samplingFrequency % Description
        startTime % Description
        endTime % Description
        breakpointsNumber % Description
        bPoints % Description
        signal % Description
        time % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            disp(class(app.startTime));
            app.signal = signalGenerator(app.samplingFrequency, app.startTime, app.endTime, app.bPoints, app.breakpointsNumber);
            app.time = linspace(app.startTime, app.endTime,(app.endTime - app.startTime)*app.samplingFrequency );
            figure(1);
            plot(app.signal);
            title('Original Signal');
        end

        % Value changed function: BreakpointsEditField
        function BreakpointsEditFieldValueChanged(app, event)
            app.breakpointsNumber = app.BreakpointsEditField.Value;
            if (app.breakpointsNumber > 0)
                app.bPoints = breakpoints(app.startTime, app.endTime, app.breakpointsNumber);
            else
                app.bPoints = 0;
            end
        end

        % Value changed function: SamplingrateEditField
        function SamplingrateEditFieldValueChanged(app, event)
            app.samplingFrequency = app.SamplingrateEditField.Value;
        end

        % Value changed function: StarttimeEditField
        function StarttimeEditFieldValueChanged(app, event)
            app.startTime = app.StarttimeEditField.Value;
        end

        % Value changed function: EndtimeEditField
        function EndtimeEditFieldValueChanged(app, event)
            app.endTime = app.EndtimeEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SamplingrateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingrateEditFieldValueChanged, true);
            app.StarttimeEditFieldLabel = uilabel(app.UIFigure);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StarttimeEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimeEditFieldValueChanged, true);
            app.EndtimeEditFieldLabel = uilabel(app.UIFigure);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndtimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndtimeEditFieldValueChanged, true);
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.BreakpointsEditFieldLabel = uilabel(app.UIFigure);
            app.BreakpointsEditFieldLabel.Text = 'Breakpoints';
            app.BreakpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BreakpointsEditField.ValueChangedFcn = createCallbackFcn(app, @BreakpointsEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Breakpoints'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.UITable.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end